classdef audiocapturer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PlayButton                      matlab.ui.control.Button
        RetryButton                     matlab.ui.control.Button
        DoneButton                      matlab.ui.control.Button
        RecordButton                    matlab.ui.control.Button
        RecordingtimesecEditField       matlab.ui.control.NumericEditField
        RecordingtimesecEditFieldLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        duration 
        recording 
        callerApp 
        input
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.duration = 0;
            app.callerApp = mainApp;
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            rec = audiorecorder;
            recordblocking(rec, app.duration);
            app.recording = getaudiodata(rec);
            t = linspace(0, app.duration, app.duration * 8000);
            plot(app.UIAxes, t, app.recording);
        end

        % Value changed function: RecordingtimesecEditField
        function RecordingtimesecEditFieldValueChanged(app, event)
            value = app.RecordingtimesecEditField.Value;
            app.duration = value;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            if isempty(app.recording) == 0
                app.callerApp.signalrenew(app.recording);
                
            end
            
            close(app.UIFigure);
        end

        % Button pushed function: RetryButton
        function RetryButtonPushed(app, event)
            app.recording = zeros(1, length(linspace(0, app.duration, 8000)));
            plot(app.UIAxes, app.recording);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            sound(app.recording, 8000);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Recording Now...')
            xlabel(app.UIAxes, 'Time ( sec)')
            ylabel(app.UIAxes, 'Amplitude')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.RecordingtimesecEditFieldLabel = uilabel(app.UIFigure);
            app.RecordingtimesecEditFieldLabel.Text = 'Recording time(sec)';
            app.RecordingtimesecEditField = uieditfield(app.UIFigure, 'numeric');
            app.RecordingtimesecEditField.ValueChangedFcn = createCallbackFcn(app, @RecordingtimesecEditFieldValueChanged, true);
            app.RecordingtimesecEditField.Value = 10;
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.RetryButton = uibutton(app.UIFigure, 'push');
            app.RetryButton.ButtonPushedFcn = createCallbackFcn(app, @RetryButtonPushed, true);
            app.RetryButton.Text = 'Retry';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = audiocapturer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end