classdef signalProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SelectdomainSwitch         matlab.ui.control.RockerSwitch
        SelectdomainSwitchLabel    matlab.ui.control.Label
        ResetButton                matlab.ui.control.Button
        PlayButton                 matlab.ui.control.Button
        ImportaudioButton          matlab.ui.control.Button
        RecordaudioButton          matlab.ui.control.Button
        TabGroup                   matlab.ui.container.TabGroup
        FrequencyModulationTab     matlab.ui.container.Tab
        Label_5                    matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        RetrievedSignalButton      matlab.ui.control.Button
        slopedetectorButton        matlab.ui.control.Button
        ChannelButton              matlab.ui.control.Button
        ModulationProcessButton    matlab.ui.control.Button
        ShowInputsignalButton_2    matlab.ui.control.Button
        AmplitudemodulationTab     matlab.ui.container.Tab
        SwapDetectorSwitch         matlab.ui.control.ToggleSwitch
        SwapDetectorSwitchLabel    matlab.ui.control.Label
        Label_9                    matlab.ui.control.Label
        Label_8                    matlab.ui.control.Label
        Label_7                    matlab.ui.control.Label
        Label_6                    matlab.ui.control.Label
        ShowInputsignalButton      matlab.ui.control.Button
        RetrievedSignalButton_2    matlab.ui.control.Button
        DetectorButton             matlab.ui.control.Button
        ChannelButton_2            matlab.ui.control.Button
        ModulationProcessButton_2  matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end


        properties (Access = private)
            t          % time
            tHR       % time vector High Res.
            orgsig  % Original signal
            fmSig   % frequency modulated signal
            amSig   % amplitude modulated signal
            deFmSig % demodulated fm signal
            deAmSig % demodulated am signal

            Y    %current Y
            X    % current X
            domain      % time or freq domain stores
            Audiocapturer % capture live audio
            Len         % length of original sound in second
            ratioM   % ratio of  the sampling rate of output and original signal

            Fs         % Sample frequency
            FsM        % Sample frequency multipler
           aDtype   % amplitude demodulator type
            Title % title of current plot

      
        end

    
    methods (Access = private)
        function results = renewplot(app)
            if isempty(app.Y)
                return;
            end
            if length(app.X) ~= length(app.Y)
                return;
            end
            if app.domain == true
                plot(app.UIAxes, app.X, app.Y);
                title(app.UIAxes, strcat(app.Title, ' In the time domain'));
                xlabel(app.UIAxes, 'time(sec)');
                xlim(app.UIAxes, [min(app.X), max(app.X)]);
                ylim(app.UIAxes, [min(app.Y) - 0.5, max(app.Y) + 0.05]);
            else
                y = fft(app.Y); %fourier
                L = length(app.Y);
                P2 = abs(y/L);
                P1 = P2(1:L/2+1);
                P1(2:end-1) = 2*P1(2:end-1);
                f = app.Fs*(0:(L/2))/L;
                
                plot(app.UIAxes, f,P1); 
                title(app.UIAxes, strcat(app.Title, ' In the frequency domain'));
                xlabel(app.UIAxes, 'f(Hz)');
                ylabel(app.UIAxes, 'Intensity');
                xlim(app.UIAxes, [min(f), max(f)]);
                ylim(app.UIAxes, [min(P1) - 0.2, max(P1) + 0.2]);
            end
        end


        function results = reset(app)
            app.ModulationProcessButton.Enable = 'on';
            app.slopedetectorButton.Enable = 'on';
            app.ModulationProcessButton_2.Enable = 'on';
            app.slopedetectorButton.Enable = 'on';
            app.FsM = 1;
            %reset all data
            app.fmSig(1:end) = 0;
            app.amSig(1:end) = 0;
            app.deFmSig(1:end) = 0;
            app.deAmSig(1:end) = 0;
            app.Y(1:end) = 0;
            renewplot(app);
        end
        
    end

    methods (Access = public)
    
        function results = signalrenew(app, signal)
            reset(app);
            app.Title = 'Input the Signal';
            drawnow
            L = length(signal);
            app.orgsig = signal;
            app.Fs = 8000;
            app.Len = length(app.orgsig)/app.Fs;
            app.t = linspace(0, L/app.Fs, L);
            app.X = app.t;
            app.Y = app.orgsig;
            app.domain = 1;
            renewplot(app);
            app.RecordaudioButton.Enable = 'on';
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            pan(app.UIAxes,'on');         
            zoom(app.UIAxes,'on'); 
            app.FsM = 1;
            app.aDtype = 1;
        end

        % Value changed function: DomainSwitch
        function DomainSwitchValueChanged(app, event)
            app.domain = ~app.domain;
            renewplot(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: FrequencyModulationTab, 
        % ShowInputsignalButton
        function ShowInputsignalButtonPushed(app, event)
           %shows the plot of input signal
            app.X = app.t;
            app.Y  = app.orgsig;
            app.FsM = 1;
            app.Title = 'Showing Input Signal';
            renewplot(app);
           
        end

        % Button pushed function: RecordaudioButton
        function RecordaudioButtonPushed(app, event)
             app.RecordaudioButton.Enable = 'off';
            app.Audiocapturer = audiocapturer(app);
        end

        % Button pushed function: ShowInputsignalButton_2
        function ShowInputsignalButton_2Pushed(app, event)
            app.X = app.t;
            app.Y  = app.orgsig;
            app.FsM = 1;
            app.Title = 'Showing Input Signal';
            renewplot(app);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: ModulationProcessButton
        function ModulationProcessButtonPushed(app, event)
             if isempty(app.orgsig) == 1
                return;
            end
            app.Title = 'FM SignaL (frequency modualated) ';
            Fc = 16000; % carrier freq
            j = 400;
            app.ratioM = 4;
            app.tHR = linspace(0, app.Len, app.Len * app.ratioM * app.Fs);
            Yn = interp1(app.t, app.orgsig, app.tHR);
            frDev = cumsum((app.tHR(2) - app.tHR(1)) * Yn) * j; %frequency deviation
            app.fmSig = cos( 2*pi*(frDev + Fc * app.tHR));
            app.X = app.tHR;
            app.Y = app.fmSig;
            renewplot(app);
            ylim(app.UIAxes, [-1, 1]);
            app.ModulationProcessButton.Enable = 'off';
        end

        % Button pushed function: ModulationProcessButton_2
        function ModulationProcessButton_2Pushed(app, event)
             if isempty(app.orgsig) == 1
                return;
            end
            app.Title = 'Amplitude Modulated Signal';
            Fc = 100;
            app.ratioM = 4;
            app.FsM = 4;
            app.tHR = linspace(0, app.Len, app.Len * app.ratioM * app.Fs);
            Yn = interp1(app.t,app.orgsig, app.tHR);
            app.amSig = Yn .* cos(2 * pi * Fc * app.tHR);
            app.X = app.tHR;
            app.Y = app.amSig;
            renewplot(app);
            app.ModulationProcessButton_2.Enable = 'off';
        end

        % Button pushed function: ImportaudioButton
        function ImportaudioButtonPushed(app, event)
             [filename, pname] = uigetfile({'*.wav'}, 'select audio files only');
            disp(strcat(pname, filename)); %pname => pathname
            
          
            if pname == 0
                return;
            end
            [app.orgsig, app.Fs] = audioread(strcat(pname, filename));
            signalrenew(app, app.orgsig);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
             sound(app.Y, app.Fs * app.FsM);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            reset(app);
        end

        % Value changed function: SelectdomainSwitch
        function SelectdomainSwitchValueChanged(app, event)
            value = app.SelectdomainSwitch.Value;
            app.domain = ~app.domain;
            renewplot(app);
        end

        % Button pushed function: ChannelButton
        function ChannelButtonPushed(app, event)
            if isempty(app.fmSig) == 1
                return;
            end
                app.FsM = 4;
                app.X = app.tHR;
                app.Y = app.fmSig;
                renewplot(app);
        end

        % Button pushed function: ChannelButton_2
        function ChannelButton_2Pushed(app, event)
             if isempty(app.amSig) == 1
                return;
             end
            app.FsM = 4;
            app.Title = 'Showing Amplitude modulated signal';
            app.X = app.tHR;
            app.Y = app.amSig;
           renewplot(app);
        
        end

        % Button pushed function: slopedetectorButton
        function slopedetectorButtonPushed(app, event)
            if isempty(app.fmSig) == 1
                return;
            end
            Dsig = 3 * envelope(diff(app.fmSig)); %Dsig= demodulated signal
            app.deFmSig = Dsig/max(Dsig);
            app.Y = app.deFmSig;
            app.X = app.tHR(1:end-1);
            app.FsM = 4;
            renewplot(app);
            ylim(app.UIAxes, [min(app.Y), max(app.Y)]);
            app.slopedetectorButton.Enable = 'off';
        end

        % Button pushed function: DetectorButton
        function DetectorButtonPushed(app, event)
             
            if isempty(app.amSig) == 1
                return;
            end
            
            app.deAmSig = app.amSig; %deAmsig= demodulated amplitude signal
            app.deAmSig(app.deAmSig < 0) = 0;
            app.Title = 'Demodulated signal for Rectifier ';
            if app.aDtype  == 1  %amplitude demodulation type
                app.deAmSig = envelope(app.deAmSig);
                app.Title = ' Demodulatd signal for Envelope';
            end
            app.FsM = 4;
            app.DetectorButton.Enable = 'off';
            app.X = app.tHR;
            app.Y = app.deAmSig;
            renewplot(app);
        end

        % Value changed function: SwapDetectorSwitch
        function SwapDetectorSwitchValueChanged(app, event)
            value = app.SwapDetectorSwitch.Value;
             switch value
                case 'Envelope'
                    app.aDtype = 1;
                case 'Rectifier'
                    app.aDtype = 0;
            end
            disp(app.aDtype);
        end

        % Button pushed function: RetrievedSignalButton_2
        function RetrievedSignalButton_2Pushed(app, event)
            %retrieved signal with respect to amplitude
            if isempty(app.deAmSig) == 1
                
                return;
            end
            app.FsM = 4;
            app.Title = 'Amplitude demodulated signal';
            app.X = app.tHR;
            app.Y = app.deAmSig;
            renewplot(app);
        end

        % Button pushed function: RetrievedSignalButton
        function RetrievedSignalButtonPushed(app, event)
            if isempty(app.deFmSig) == 1
                return;
            end
            app.FsM = 4;
            app.Y = app.deFmSig;
            app.X = app.tHR(1:end-1);
            app.Title = 'frequnecy DM signal';
            renewplot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input signal')
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FrequencyModulationTab = uitab(app.TabGroup);
            app.FrequencyModulationTab.SizeChangedFcn = createCallbackFcn(app, @ShowInputsignalButtonPushed, true);
            app.FrequencyModulationTab.Title = 'Frequency Modulation';
            app.ShowInputsignalButton_2 = uibutton(app.FrequencyModulationTab, 'push');
            app.ShowInputsignalButton_2.ButtonPushedFcn = createCallbackFcn(app, @ShowInputsignalButton_2Pushed, true);
            app.ShowInputsignalButton_2.Text = 'Show Input signal';
            app.ModulationProcessButton = uibutton(app.FrequencyModulationTab, 'push');
            app.ModulationProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ModulationProcessButtonPushed, true);
            app.ModulationProcessButton.Text = 'Modulation Process';
            app.ChannelButton = uibutton(app.FrequencyModulationTab, 'push');
            app.ChannelButton.ButtonPushedFcn = createCallbackFcn(app, @ChannelButtonPushed, true);
            app.ChannelButton.Text = 'Channel';
            app.slopedetectorButton = uibutton(app.FrequencyModulationTab, 'push');
            app.slopedetectorButton.ButtonPushedFcn = createCallbackFcn(app, @slopedetectorButtonPushed, true);
            app.slopedetectorButton.Text = 'slope detector';
            app.RetrievedSignalButton = uibutton(app.FrequencyModulationTab, 'push');
            app.RetrievedSignalButton.ButtonPushedFcn = createCallbackFcn(app, @RetrievedSignalButtonPushed, true);
            app.RetrievedSignalButton.Text = 'Retrieved Signal';
            app.Label = uilabel(app.FrequencyModulationTab);
            app.Label.Text = ' ==>';
            app.Label_3 = uilabel(app.FrequencyModulationTab);
            app.Label_3.Text = ' ==>';
            app.Label_4 = uilabel(app.FrequencyModulationTab);
            app.Label_4.Text = ' ==>';
            app.Label_5 = uilabel(app.FrequencyModulationTab);
            app.Label_5.Text = ' ==>';
            app.AmplitudemodulationTab = uitab(app.TabGroup);
            app.AmplitudemodulationTab.Title = 'Amplitude modulation';
            app.ModulationProcessButton_2 = uibutton(app.AmplitudemodulationTab, 'push');
            app.ModulationProcessButton_2.ButtonPushedFcn = createCallbackFcn(app, @ModulationProcessButton_2Pushed, true);
            app.ModulationProcessButton_2.Text = 'Modulation Process';
            app.ChannelButton_2 = uibutton(app.AmplitudemodulationTab, 'push');
            app.ChannelButton_2.ButtonPushedFcn = createCallbackFcn(app, @ChannelButton_2Pushed, true);
            app.ChannelButton_2.Text = 'Channel';
            app.DetectorButton = uibutton(app.AmplitudemodulationTab, 'push');
            app.DetectorButton.ButtonPushedFcn = createCallbackFcn(app, @DetectorButtonPushed, true);
            app.DetectorButton.Text = 'Detector';
            app.RetrievedSignalButton_2 = uibutton(app.AmplitudemodulationTab, 'push');
            app.RetrievedSignalButton_2.ButtonPushedFcn = createCallbackFcn(app, @RetrievedSignalButton_2Pushed, true);
            app.RetrievedSignalButton_2.Text = 'Retrieved Signal';
            app.ShowInputsignalButton = uibutton(app.AmplitudemodulationTab, 'push');
            app.ShowInputsignalButton.ButtonPushedFcn = createCallbackFcn(app, @ShowInputsignalButtonPushed, true);
            app.ShowInputsignalButton.Text = 'Show Input signal';
            app.Label_6 = uilabel(app.AmplitudemodulationTab);
            app.Label_6.Text = ' ==>';
            app.Label_7 = uilabel(app.AmplitudemodulationTab);
            app.Label_7.Text = ' ==>';
            app.Label_8 = uilabel(app.AmplitudemodulationTab);
            app.Label_8.Text = ' ==>';
            app.Label_9 = uilabel(app.AmplitudemodulationTab);
            app.Label_9.Text = ' ==>';
            app.SwapDetectorSwitchLabel = uilabel(app.AmplitudemodulationTab);
            app.SwapDetectorSwitchLabel.Text = 'Swap Detector';
            app.SwapDetectorSwitch = uiswitch(app.AmplitudemodulationTab, 'toggle');
            app.SwapDetectorSwitch.Items = {'Rectifier', 'Envelope'};
            app.SwapDetectorSwitch.ValueChangedFcn = createCallbackFcn(app, @SwapDetectorSwitchValueChanged, true);
            app.SwapDetectorSwitch.Value = 'Rectifier';
            app.RecordaudioButton = uibutton(app.UIFigure, 'push');
            app.RecordaudioButton.ButtonPushedFcn = createCallbackFcn(app, @RecordaudioButtonPushed, true);
            app.RecordaudioButton.Text = 'Record audio';
            app.ImportaudioButton = uibutton(app.UIFigure, 'push');
            app.ImportaudioButton.ButtonPushedFcn = createCallbackFcn(app, @ImportaudioButtonPushed, true);
            app.ImportaudioButton.Text = 'Import audio';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play ';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SelectdomainSwitchLabel = uilabel(app.UIFigure);
            app.SelectdomainSwitchLabel.Text = 'Select domain';
            app.SelectdomainSwitch = uiswitch(app.UIFigure, 'rocker');
            app.SelectdomainSwitch.Items = {'Time', 'f(Hz).'};
            app.SelectdomainSwitch.ValueChangedFcn = createCallbackFcn(app, @SelectdomainSwitchValueChanged, true);
            app.SelectdomainSwitch.Value = 'Time';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signalProcessing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end